classdef SelectData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        Cancel      matlab.ui.control.Button
        Confirm     matlab.ui.control.Button
        Attitude    matlab.ui.control.Button
        Deepth      matlab.ui.control.Button
        TextArea_2  matlab.ui.control.TextArea
        Label_2     matlab.ui.control.Label
        TextArea    matlab.ui.control.TextArea
        Label       matlab.ui.control.Label
    end

    
    properties (Access = private)
        callapp1 % main app 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.callapp1=mainapp;
        end

        % Button pushed function: Confirm
        function ConfirmButtonPushed(app, event)
            if(app.TextArea_2.Value=="")
               fig = uifigure;
               uialert(fig,'erro:属性采样数据不能为空','数据缺少');
               return;
            end
            if(updateFilePath(app.callapp1,app.TextArea.Value,app.TextArea_2.Value))
               delete(app);
            else
                fig = uifigure;
                uialert(fig,'数据读取错误，请检查数据格式','数据缺少');
            end
            return;
        end

        % Button pushed function: Attitude
        function AttitudeButtonPushed(app, event)
            [file,path] = uigetfile( {'*.xls;*.xlsx',...
                                       'Excel Files (*.xls,*.xlsx)'});
            if(file==""||path=="")
                return; 
            end
            app.TextArea.Value=[path,file];
        end

        % Button pushed function: Deepth
        function DeepthButtonPushed(app, event)
            [file,path] = uigetfile( {'*.xls;*.xlsx',...
                                       'Excel Files (*.xls,*.xlsx)'});
            if(file==""||path=="")
                return;
            end
            app.TextArea_2.Value=[path,file] ;
            %assignin('base','deepdata',app.TextArea_2.Value);%%
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %app.callapp1.Button.Enable='off';
            delete(app);
        end

        % Button pushed function: Cancel
        function CancelButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '选择产状数据';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'C:\Users\21501\Desktop\NFattitude.xlsx'};
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '选择埋深数据';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Value = {'C:\Users\21501\Desktop\NorthDicengPoint.xls'};
            app.Deepth = uibutton(app.UIFigure, 'push');
            app.Deepth.ButtonPushedFcn = createCallbackFcn(app, @DeepthButtonPushed, true);
            app.Deepth.Text = '浏览';
            app.Attitude = uibutton(app.UIFigure, 'push');
            app.Attitude.ButtonPushedFcn = createCallbackFcn(app, @AttitudeButtonPushed, true);
            app.Attitude.Text = '浏览';
            app.Confirm = uibutton(app.UIFigure, 'push');
            app.Confirm.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.Confirm.Text = '确认';
            app.Cancel = uibutton(app.UIFigure, 'push');
            app.Cancel.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.Cancel.Text = '取消';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SelectData(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end