classdef SelectSolution < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Cancel            matlab.ui.control.Button
        Confirm           matlab.ui.control.Button
        ZmEditField       matlab.ui.control.NumericEditField
        ZmEditFieldLabel  matlab.ui.control.Label
        YmEditField       matlab.ui.control.NumericEditField
        YmEditFieldLabel  matlab.ui.control.Label
        XmEditField       matlab.ui.control.NumericEditField
        XmEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        callingApp% Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.callingApp=mainapp;
        end

        % Button pushed function: Confirm
        function ConfirmButtonPushed(app, event)
            bool=selectSolution(app.callingApp,app.XmEditField.Value,app.YmEditField.Value,app.ZmEditField.Value);
            if(~bool)
                fig = uifigure;
                uialert(fig,'分辨率设置非法，请检查数值设置','数据错误');
                return;           
            end
            setSignal(app.callingApp,true);
            delete(app);
        end

        % Button pushed function: Cancel
        function CancelButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.XmEditFieldLabel = uilabel(app.UIFigure);
            app.XmEditFieldLabel.Text = 'X(m)';
            app.XmEditField = uieditfield(app.UIFigure, 'numeric');
            app.XmEditField.Value = 100;
            app.YmEditFieldLabel = uilabel(app.UIFigure);
            app.YmEditFieldLabel.Text = 'Y(m)';
            app.YmEditField = uieditfield(app.UIFigure, 'numeric');
            app.YmEditField.Value = 100;
            app.ZmEditFieldLabel = uilabel(app.UIFigure);
            app.ZmEditFieldLabel.Text = 'Z(m)';
            app.ZmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZmEditField.Value = 25;
            app.Confirm = uibutton(app.UIFigure, 'push');
            app.Confirm.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.Confirm.Text = '确认';
            app.Cancel = uibutton(app.UIFigure, 'push');
            app.Cancel.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.Cancel.Text = '取消';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SelectSolution(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end