classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        file_menu       matlab.ui.container.Menu
        import_data     matlab.ui.container.Menu
        import_field    matlab.ui.container.Menu
        export_field    matlab.ui.container.Menu
        Label_3         matlab.ui.control.Label
        SelectBaseFuc   matlab.ui.control.DropDown
        TabGroup        matlab.ui.container.TabGroup
        Tab_datapoints  matlab.ui.container.Tab
        fig_datapoints  matlab.ui.control.UIAxes
        Tab_field       matlab.ui.container.Tab
        fig_field       matlab.ui.control.UIAxes
        GetModel        matlab.ui.control.Button
        SelectMethod    matlab.ui.control.DropDown
        Label_2         matlab.ui.control.Label
    end

    
    properties (Access = public)
        %dataFile % 数据文件的路径
        attitudeData;
        attributeData;
        valueGrid;
        grid_X;
        grid_Y;
        grid_Z;
        dataMax;
        dataMin;
        soluion=[100,100,25];
        selectDataApp;
        setSolution;
        signal=false;
        value_list;
        isoSurfaces = [];
        
    end
    
    methods (Access = public)
        function bool=updateFilePath(app,attitudeFile,attributeFile)
            bool=true;
            try
                if(attitudeFile{1,1}~="")
                    app.attitudeData=xlsread(attitudeFile{1,1});
                    app.SelectMethod.Items={'RBF', 'HRBF', 'AdaHRBF'};
                end
                if(attributeFile{1,1}~="")
                    app.attributeData=xlsread(attributeFile{1,1});
                end
                app.value_list = unique(app.attributeData(:,4)); 
                app.value_list = [4872,7233,8429,9145,9674,10602,11717,8429,9145,9674,10602,11142];
%                 assignin('base','attitudeData', app.attitudeData);
%                 assignin('base','attributeData',app.attributeData);
                app.dataMax=max([app.attributeData(:,1:3);app.attitudeData(:,1:3)]);
                app.dataMin=min([app.attributeData(:,1:3);app.attitudeData(:,1:3)]);
                app.fig_datapoints.XLim=[app.dataMin(1,1),app.dataMax(1,1)];
                app.fig_datapoints.YLim=[app.dataMin(1,2),app.dataMax(1,2)];
                app.fig_datapoints.ZLim=[app.dataMin(1,3),app.dataMax(1,3)];
                app.attitudeData=AttitudeToVector(app.attitudeData);
            catch 
                bool =false;
                return;
            end
            scatter3(app.fig_datapoints,app.attributeData(:,1),app.attributeData(:,2),app.attributeData(:,3),18,[0.98,0.45,0.25],'filled');
            hold(app.fig_datapoints,'on') ;
            grid(app.fig_datapoints,'on');
            quiver3(app.fig_datapoints,app.attitudeData(:,1),app.attitudeData(:,2),app.attitudeData(:,3),app.attitudeData(:,4),app.attitudeData(:,5),app.attitudeData(:,6),0.15,'LineWidth',2,'Color',[0.46,0.66,0.88]);
            hold(app.fig_datapoints,'off') ;
            return;
        end
        
        function bool=selectSolution(app,solution_x,solution_y,solution_z)
            bool=true;
            if(solution_x==0||solution_y==0||solution_z==0)
                bool=false;
                return;
            end
            try
                app.soluion(1,1)=solution_x;
                app.soluion(1,2)=solution_y;
                app.soluion(1,3)=solution_z;
            catch
                bool=false;
                return;
            end
            return;
        end
        
        function setSignal(app,signal)
            app.signal=signal;
        end
        
        function updateFieldView(app)
            scatter3(app.fig_field,app.grid_X(:),app.grid_Y(:),app.grid_Z(:),25,app.valueGrid(:),'.');
            hold(app.fig_field,'off') ;
        end
        function updateLithView(app)
%             num=size(app.value_list,1);
            color_list={'#ffe7ff','#fedfff','#ecffb0','#e2e2e2','#d0cfe1','#c5c4ca','#ffdfc0','#ecffb0','#e2e2e2','#d0cfe1','#c5c4ca','#ffe7cf'};
            figure('Name','IsoSurfaces');
            light('position',[-0.5,-0.5,0.5],'style','local','color','[0.5,0.5,0.5]')
            grid on; box on;
%             axis(area);
            axis equal;
            set(gca,'XLim',[app.dataMin(1,1),app.dataMax(1,1)]);
            set(gca,'YLim',[app.dataMin(1,2),app.dataMax(1,2)]);
            set(gca,'ZLim',[900,1150]);
%             set(gca,'ZLim',[app.dataMin(1,3),app.dataMax(1,3)]);
            for i=1:7
               app.isoSurfaces= isosurface(app.grid_X,app.grid_Y,app.grid_Z, app.valueGrid,app.value_list(i));
                pic = patch(isosurface(app.grid_X,app.grid_Y,app.grid_Z,app.valueGrid,app.value_list(i)));
                isonormals(app.grid_X,app.grid_Y,app.grid_Z,app.valueGrid,pic)
                set(pic,'FaceColor',color_list{i},'EdgeColor','none');
                hold on;
            end
            hold off
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %updateFilePath(app,app.attributeData,app.attitudeData);
        end

        % Button pushed function: GetModel
        function GetModelButtonPushed(app, event)
            %currentMethod=app.SelectMethod.Value;
             app.setSolution=SelectSolution(app);
             waitfor(app.setSolution);
             if(~app.signal)
                 return;
             end
             s= app.SelectMethod.Value;
             switch s
                 case 'RBF'
                    [app.valueGrid,app.grid_X,app.grid_Y,app.grid_Z]=RBFGetModel(app.attributeData,app.soluion,1e-4,1);
                 case 'HRBF'
                    [app.valueGrid,app.grid_X,app.grid_Y,app.grid_Z]=HRBFGetModel(app.attributeData,app.attitudeData,app.soluion,1e-4,1);
                 case 'AdaHRBF'
                    [app.valueGrid,app.grid_X,app.grid_Y,app.grid_Z]=AdaHRBFGetModel(app.attributeData,app.attitudeData,app.soluion,1e-4,1,100,0.01,300);
             end
             app.signal=false;
             app.updateFieldView();
             app.updateLithView();
        end

        % Menu selected function: import_data
        function import_dataMenuSelected(app, event)
            app.selectDataApp=SelectData(app);
            waitfor(app.selectDataApp);
            %app.Button.Enable='off';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.selectDataApp);
            delete(app);
        end

        % Menu selected function: export_field
        function export_fieldMenuSelected(app, event)
            filter ={'*.csv';'*.xls';'*.xlsx'};
            [file,path] = uiputfile(filter);
            display(fullfile(path,file));
            if isequal(file,0)||isequal(path,0)
                return;
            else
                SavePotentialField(file,path,app.grid_X,app.grid_Y,app.grid_Z,app.valueGrid)
            end
        end

        % Menu selected function: import_field
        function import_fieldMenuSelected(app, event)
            filter ={'*.csv';'*.xls';'*.xlsx'};
            [file,path] = uigetfile(filter);
            A = readmatrix(fullfile(path,file));
            app.grid_X=A(:,1);
            app.grid_Y=A(:,2);
            app.grid_Z=A(:,3);
            app.valueGrid=A(:,4);
            app.updateFieldView;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.file_menu = uimenu(app.UIFigure);
            app.file_menu.Text = '文件';
            app.import_data = uimenu(app.file_menu);
            app.import_data.MenuSelectedFcn = createCallbackFcn(app, @import_dataMenuSelected, true);
            app.import_data.Text = '导入采样数据';
            app.import_field = uimenu(app.file_menu);
            app.import_field.MenuSelectedFcn = createCallbackFcn(app, @import_fieldMenuSelected, true);
            app.import_field.Text = '导入位势场数据';
            app.export_field = uimenu(app.file_menu);
            app.export_field.MenuSelectedFcn = createCallbackFcn(app, @export_fieldMenuSelected, true);
            app.export_field.Text = '导出位势场数据';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '选择方法';
            app.SelectMethod = uidropdown(app.UIFigure);
            app.SelectMethod.Items = {'RBF'};
            app.SelectMethod.Value = 'RBF';
            app.GetModel = uibutton(app.UIFigure, 'push');
            app.GetModel.ButtonPushedFcn = createCallbackFcn(app, @GetModelButtonPushed, true);
            app.GetModel.Text = '计算';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab_datapoints = uitab(app.TabGroup);
            app.Tab_datapoints.Title = '采样点视图';
            app.fig_datapoints = uiaxes(app.Tab_datapoints);
            title(app.fig_datapoints, '采样点坐标')
            xlabel(app.fig_datapoints, 'X')
            ylabel(app.fig_datapoints, 'Y')
            zlabel(app.fig_datapoints, 'Z')
            app.Tab_field = uitab(app.TabGroup);
            app.Tab_field.Title = '位势场视图';
            app.fig_field = uiaxes(app.Tab_field);
            title(app.fig_field, '位势场视图')
            xlabel(app.fig_field, 'X')
            ylabel(app.fig_field, 'Y')
            zlabel(app.fig_field, 'Z')
            app.SelectBaseFuc = uidropdown(app.UIFigure);
            app.SelectBaseFuc.Items = {'Cubic', ''};
            app.SelectBaseFuc.Value = 'Cubic';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '选择基函数';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end